classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        WelcomeAdminLabel             matlab.ui.control.Label
        InsertDatasetPanel            matlab.ui.container.Panel
        Label                         matlab.ui.control.Label
        ChooseFolderButton            matlab.ui.control.Button
        SelectFeatureExtractionPanel  matlab.ui.container.Panel
        GLCMCheckBox                  matlab.ui.control.CheckBox
        LESHCheckBox                  matlab.ui.control.CheckBox
        SelectSegmentationAlgorithmPanel  matlab.ui.container.Panel
        HammocheAlgorithmCheckBox     matlab.ui.control.CheckBox
        DragonflyAlgorithmCheckBox    matlab.ui.control.CheckBox
        RunSVMButton                  matlab.ui.control.Button
        BackButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        folder % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            %image = uigetdir('C:\')
            app.folder = uigetdir('C:\Users\New Hp\Documents\MATLAB\fit3162\Code');
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app1
            app.UIFigure.Visible = 'off';
        end

        % Value changed function: HammocheAlgorithmCheckBox
        function HammocheAlgorithmCheckBoxValueChanged(app, event)
            app.folder
            value = app.HammocheAlgorithmCheckBox.Value;
            
            var=load('C:\Users\Sook Mun\Google Drive\Y3S2\CS2\fit3162\Code\Hammouche\segmenta.m'); % specify the path to mat file
            load C:\Users\Sook Mun\Google Drive\Y3S2\CS2\fit3162\Code\Hammouche\glcmHammouche.mlx
            load C:\Users\Sook Mun\Google Drive\Y3S2\CS2\fit3162\Code\Hammouche\fun_custo.m
            show(value)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeAdminLabel = uilabel(app.UIFigure);
            app.WelcomeAdminLabel.Text = 'Welcome Admin';
            app.InsertDatasetPanel = uipanel(app.UIFigure);
            app.InsertDatasetPanel.Title = 'Insert Dataset';
            app.Label = uilabel(app.InsertDatasetPanel);
            app.Label.Text = '';
            app.ChooseFolderButton = uibutton(app.InsertDatasetPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.SelectFeatureExtractionPanel = uipanel(app.UIFigure);
            app.SelectFeatureExtractionPanel.Title = 'Select Feature Extraction';
            app.GLCMCheckBox = uicheckbox(app.SelectFeatureExtractionPanel);
            app.GLCMCheckBox.Text = 'GLCM';
            app.LESHCheckBox = uicheckbox(app.SelectFeatureExtractionPanel);
            app.LESHCheckBox.Text = 'LESH';
            app.SelectSegmentationAlgorithmPanel = uipanel(app.UIFigure);
            app.SelectSegmentationAlgorithmPanel.Title = 'Select Segmentation Algorithm';
            app.HammocheAlgorithmCheckBox = uicheckbox(app.SelectSegmentationAlgorithmPanel);
            app.HammocheAlgorithmCheckBox.ValueChangedFcn = createCallbackFcn(app, @HammocheAlgorithmCheckBoxValueChanged, true);
            app.HammocheAlgorithmCheckBox.Text = 'Hammoche  Algorithm';
            app.DragonflyAlgorithmCheckBox = uicheckbox(app.SelectSegmentationAlgorithmPanel);
            app.DragonflyAlgorithmCheckBox.Text = 'Dragonfly Algorithm';
            app.RunSVMButton = uibutton(app.UIFigure, 'push');
            app.RunSVMButton.Text = 'Run SVM';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end