classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        WelcomeAdminLabel            matlab.ui.control.Label
        InsertDatasetPanel           matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        ChooseFolderButton           matlab.ui.control.Button
        ChooseCSVButton              matlab.ui.control.Button
        RunSVMButton                 matlab.ui.control.Button
        BackButton                   matlab.ui.control.Button
        SelectSegmentationAlgorithmButtonGroup  matlab.ui.container.ButtonGroup
        HammoucheAlgorithmButton     matlab.ui.control.RadioButton
        RegionGrowingAlgoritmButton  matlab.ui.control.RadioButton
        SelectFeatureExtractionButtonGroup  matlab.ui.container.ButtonGroup
        GLCMButton                   matlab.ui.control.RadioButton
        LESHButton                   matlab.ui.control.RadioButton
        Label_2                      matlab.ui.control.Label
        TrainpercentageLabel         matlab.ui.control.Label
        TrainpercentageSlider        matlab.ui.control.Slider
        ResultsTextAreaLabel         matlab.ui.control.Label
        ResultsTextArea              matlab.ui.control.TextArea
        RunningLampLabel             matlab.ui.control.Label
        RunningLamp                  matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        folder="" % Description
        csvTable="" % csv file of actual condition
        training_percentage="" % training_percentage that user choose
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            app.folder = uigetdir('C:\');
            figure(app.UIFigure);
            try
                breastImds=imageDatastore(app.folder);
            catch ME
                message = {'Wrong File Selected!','Choose a folder containing images'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','error');
                app.folder="";
               return          
            end
            app.Label_2.Text=app.folder;
        end

        % Button pushed function: ChooseCSVButton
        function ChooseCSVButtonPushed(app, event)
            [file,path] = uigetfile({;'*.xlsx';'*.xls';'*.csv';}); % allowing user to choose excel files
            figure(app.UIFigure);
            if isequal(file, 0)
                message = {'User Aborted File Choosing!','Please Select a csv folder'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','warning');
               return  
            else
                file = fullfile(path, file);
            end
            try
                app.csvTable=readtable(file);
                [~,n] = size(app.csvTable);
                assert(n==2)
                %
                app.csvTable=file;
            catch ME
                message = {'Wrong File Selected!','Choose a csv file','CSV file must contain 2 columns representing Image Name and Actual Condition'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','error');
                app.csvTable="";
                return
            end
        end

        % Value changed function: TrainpercentageSlider
        function TrainpercentageSliderValueChanged(app, event)
            %function to allow user to choose the train percentage
            value = app.TrainpercentageSlider.Value;
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            % move the slider to that option
            event.Source.Value = event.Source.MajorTicks(minIdx);
            if minIdx == 1
                app.training_percentage = 0.50;
            elseif minIdx ==2
                app.training_percentage = 0.55;
            elseif minIdx ==3
               app.training_percentage = 0.60;
            elseif minIdx ==4
               app.training_percentage = 0.65;
            elseif minIdx ==5
               app.training_percentage = 0.70;
            elseif minIdx ==6
               app.training_percentage = 0.75;
            elseif minIdx ==7
               app.training_percentage = 0.80;
            elseif minIdx ==8
               app.training_percentage = 0.85;
            elseif minIdx ==9
               app.training_percentage = 0.90;
            elseif minIdx ==10
               app.training_percentage = 0.95;
            elseif minIdx ==11
               app.training_percentage = 1;
            end
                        
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app1
            app.UIFigure.Visible = 'off';
        end

        % Button pushed function: RunSVMButton
        function RunSVMButtonPushed(app, event)
            %getting user input
            if (isa(app.training_percentage,'double'))==0
                message = {'Training Percentage Not Selected!','Please ensure you had input all appropriate data','Choose the percentage of dataset you want to train'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','error');
                return
            end
            if app.folder==""
                message = {'Dataset Folder Not Selected!','Please ensure you had input all appropriate data','Input a folder of images'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','error');
                return
            end
            if app.csvTable==""
                message = {'CSV File Not Selected!','Please ensure you had input all appropriate data','Input a csv file'};
                uialert(app.UIFigure,message,'Incorrect Input','Icon','error');
                return
            end
            app.csvTable=readtable(app.csvTable);
            seg=app.SelectSegmentationAlgorithmButtonGroup.SelectedObject.Text;
            fea=app.SelectFeatureExtractionButtonGroup.SelectedObject.Text;
            app.RunningLamp.Color='yellow'; %changing the state of the lamp
            drawnow  %updating the UI
            % Hammouche
            if seg=="Hammouche Algorithm"
                if fea=="GLCM"
                    %Hammouche Algorithm +GLCM 
                    outputTable = hammoucheGLCM(app.folder,app.csvTable);
                    [con_matric,noClasses,accuracy]=SVM_GLCM(outputTable,app.training_percentage);
                else
                    %Hammouche Algorithm +LESH 
                    outputTable=hammoucheLESH(app.folder,app.csvTable);
                    [con_matric,noClasses,accuracy]=SVM_LESH(outputTable,app.training_percentage);
                end
                
            % Region Growing
            else
                if fea=="LESH"
                    %Region Growing +LESH
                    outputTable = regionGrowingLESH(app.folder,app.csvTable);
                    [con_matric,noClasses,accuracy]=SVM_LESH(outputTable,app.training_percentage);
                    
                else
                    %Region Growing +GLCM 
                    outputTable= regionGrowingGLCM(app.folder,app.csvTable);
                    [con_matric,noClasses,accuracy]=SVM_GLCM(outputTable,app.training_percentage);
                end
            end
            app.RunningLamp.Color='green'; % change lamp to green when segmentation, feature extraction and SVM has been completed
            drawnow %updating the UI
            %makign the results text Area Visible
            app.ResultsTextArea.Visible = 'on';
            app.ResultsTextAreaLabel.Visible= 'on';
            %displaying on the text area
            app.ResultsTextArea.Value{1}=['There are ' num2str(noClasses) ' classes detected'];
            app.ResultsTextArea.Value{2} =['Accuracy Of Implementation:' num2str(accuracy)];
            app.ResultsTextArea.Value{3} =('Confusion Matrix Of The Implementation');
            app.ResultsTextArea.Value{4} =mat2str(con_matric);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WelcomeAdminLabel = uilabel(app.UIFigure);
            app.WelcomeAdminLabel.Text = 'Welcome Admin';
            app.InsertDatasetPanel = uipanel(app.UIFigure);
            app.InsertDatasetPanel.Title = 'Insert Dataset';
            app.Label = uilabel(app.InsertDatasetPanel);
            app.Label.Text = '';
            app.ChooseFolderButton = uibutton(app.InsertDatasetPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.ChooseCSVButton = uibutton(app.InsertDatasetPanel, 'push');
            app.ChooseCSVButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVButtonPushed, true);
            app.ChooseCSVButton.Text = 'Choose CSV';
            app.RunSVMButton = uibutton(app.UIFigure, 'push');
            app.RunSVMButton.ButtonPushedFcn = createCallbackFcn(app, @RunSVMButtonPushed, true);
            app.RunSVMButton.Text = 'Run SVM';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.SelectSegmentationAlgorithmButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectSegmentationAlgorithmButtonGroup.Title = 'Select Segmentation Algorithm';
            app.HammoucheAlgorithmButton = uiradiobutton(app.SelectSegmentationAlgorithmButtonGroup);
            app.HammoucheAlgorithmButton.Text = 'Hammouche Algorithm';
            app.HammoucheAlgorithmButton.Value = true;
            app.RegionGrowingAlgoritmButton = uiradiobutton(app.SelectSegmentationAlgorithmButtonGroup);
            app.RegionGrowingAlgoritmButton.Text = 'Region Growing Algoritm';
            app.SelectFeatureExtractionButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectFeatureExtractionButtonGroup.Title = 'Select Feature Extraction';
            app.GLCMButton = uiradiobutton(app.SelectFeatureExtractionButtonGroup);
            app.GLCMButton.Text = 'GLCM';
            app.GLCMButton.Value = true;
            app.LESHButton = uiradiobutton(app.SelectFeatureExtractionButtonGroup);
            app.LESHButton.Text = 'LESH';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = {''; ''};
            app.TrainpercentageLabel = uilabel(app.UIFigure);
            app.TrainpercentageLabel.Text = 'Train percentage (%)';
            app.TrainpercentageSlider = uislider(app.UIFigure);
            app.TrainpercentageSlider.Limits = [0 10];
            app.TrainpercentageSlider.MajorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.TrainpercentageSlider.MajorTickLabels = {'50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.TrainpercentageSlider.ValueChangedFcn = createCallbackFcn(app, @TrainpercentageSliderValueChanged, true);
            app.TrainpercentageSlider.MinorTicks = [];
            app.ResultsTextAreaLabel = uilabel(app.UIFigure);
            app.ResultsTextAreaLabel.Visible = 'off';
            app.ResultsTextAreaLabel.Text = 'Results:';
            app.ResultsTextArea = uitextarea(app.UIFigure);
            app.ResultsTextArea.Editable = 'off';
            app.ResultsTextArea.Visible = 'off';
            app.RunningLampLabel = uilabel(app.UIFigure);
            app.RunningLampLabel.Text = 'Running';
            app.RunningLamp = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end