classdef UIAttempt2_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        text2          matlab.ui.control.Label
        browse_id      matlab.ui.control.Button
        seg_menu_id    matlab.ui.container.ButtonGroup
        reg_grow_id    matlab.ui.control.RadioButton
        ham_id         matlab.ui.control.RadioButton
        fea_extrac_id  matlab.ui.container.ButtonGroup
        glcm_id        matlab.ui.control.RadioButton
        lesh_id        matlab.ui.control.RadioButton
        run_butt_id    matlab.ui.control.Button
        uitable1       matlab.ui.control.Table
        message_id     matlab.ui.control.Label
    end

    
    methods (Access = private)
        function call_segmentation_feat_extraction(app, hObject, eventdata, handles)
            seg_algo=get(handles.seg_menu_id,'SelectedObject');
            seg_algo=get(seg_algo,'Tag');
            
            feaext_algo=get(handles.fea_extrac_id,'SelectedObject');
            feaext_algo=get(feaext_algo,'Tag');
            
            folder_name=handles.folder;
            
            if seg_algo=="reg_grow_id"
                if feaext_algo=="glcm_id"
                    regionGrowingGLCM(folder_name);
                else
                    regionGrowingLESH(folder_name);
                end
            else
                if feaext_algo=="glcm_id"
                    hammoucheGLCM(folder_name);
                else
                    hammoucheLESH(folder_name);
                end
            end
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.ColumnFormat = {[] [] []};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UIAttempt2_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to UIAttempt2 (see VARARGIN)
            
            % Choose default command line output for UIAttempt2
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: browse_id
        function browse_id_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to browse_id (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.folder = uigetdir('C:\');
            guidata(hObject,handles);
        end

        % Button pushed function: run_butt_id
        function run_butt_id_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to run_butt_id (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            handles.folder;
            set(handles.message_id,"String","Starting");
            call_segmentation_feat_extraction(app, hObject, eventdata, handles);
            set(handles.message_id,"String","Segementation & Feature Extraction Done");
            confusion_met=SVM();
            set(handles.message_id,"String","Completed!")
            set(handles.uitable1,'Data',confusion_met);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'UIAttempt2';
            app.figure1.Tag = 'figure1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Click button to browse folder of images';
            app.browse_id = uibutton(app.figure1, 'push');
            app.browse_id.ButtonPushedFcn = createCallbackFcn(app, @browse_id_Callback, true);
            app.browse_id.Tag = 'browse_id';
            app.browse_id.Text = 'Browse';
            app.seg_menu_id = uibuttongroup(app.figure1);
            app.seg_menu_id.Title = 'Segmentation';
            app.seg_menu_id.Tag = 'seg_menu_id';
            app.reg_grow_id = uiradiobutton(app.seg_menu_id);
            app.reg_grow_id.Tag = 'reg_grow_id';
            app.reg_grow_id.Text = 'Region Growing';
            app.reg_grow_id.Value = true;
            app.ham_id = uiradiobutton(app.seg_menu_id);
            app.ham_id.Tag = 'ham_id';
            app.ham_id.Text = 'Hammouche Algorithm';
            app.fea_extrac_id = uibuttongroup(app.figure1);
            app.fea_extrac_id.Title = 'Feature Extraction';
            app.fea_extrac_id.Tag = 'fea_extrac_id';
            app.glcm_id = uiradiobutton(app.fea_extrac_id);
            app.glcm_id.Tag = 'glcm_id';
            app.glcm_id.Text = 'GLCM';
            app.glcm_id.Value = true;
            app.lesh_id = uiradiobutton(app.fea_extrac_id);
            app.lesh_id.Tag = 'lesh_id';
            app.lesh_id.Text = 'LESH';
            app.run_butt_id = uibutton(app.figure1, 'push');
            app.run_butt_id.ButtonPushedFcn = createCallbackFcn(app, @run_butt_id_Callback, true);
            app.run_butt_id.Tag = 'run_butt_id';
            app.run_butt_id.Text = 'RUN SVM';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnEditable = [false false false];
            app.uitable1.Tag = 'uitable1';
            app.message_id = uilabel(app.figure1);
            app.message_id.Tag = 'message_id';
            app.message_id.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UIAttempt2_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)UIAttempt2_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end